classdef posture_editer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GenerateButton           matlab.ui.control.Button
        CenterPanel              matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        RightPanel               matlab.ui.container.Panel
        HeadSliderLabel          matlab.ui.control.Label
        HeadSlider               matlab.ui.control.Slider
        HeadEditField            matlab.ui.control.NumericEditField
        EditField_2              matlab.ui.control.NumericEditField
        ChestSliderLabel         matlab.ui.control.Label
        ChestSlider              matlab.ui.control.Slider
        EditField_3              matlab.ui.control.NumericEditField
        WaistSliderLabel         matlab.ui.control.Label
        WaistSlider              matlab.ui.control.Slider
        EditField_4              matlab.ui.control.NumericEditField
        LeftArmSliderLabel       matlab.ui.control.Label
        LeftArmSlider            matlab.ui.control.Slider
        EditField_5              matlab.ui.control.NumericEditField
        LeftForearmSliderLabel   matlab.ui.control.Label
        LeftForearmSlider        matlab.ui.control.Slider
        EditField_6              matlab.ui.control.NumericEditField
        RightArmSliderLabel      matlab.ui.control.Label
        RightArmSlider           matlab.ui.control.Slider
        EditField_7              matlab.ui.control.NumericEditField
        RightForearmSliderLabel  matlab.ui.control.Label
        RightForearmSlider       matlab.ui.control.Slider
        EditField_8              matlab.ui.control.NumericEditField
        LeftThighSliderLabel     matlab.ui.control.Label
        LeftThighSlider          matlab.ui.control.Slider
        EditField_9              matlab.ui.control.NumericEditField
        LeftCalfSliderLabel      matlab.ui.control.Label
        LeftCalfSlider           matlab.ui.control.Slider
        EditField_10             matlab.ui.control.NumericEditField
        RightThighSliderLabel    matlab.ui.control.Label
        RightThighSlider         matlab.ui.control.Slider
        EditField_11             matlab.ui.control.NumericEditField
        RightCalfSliderLabel     matlab.ui.control.Label
        RightCalfSlider          matlab.ui.control.Slider
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        posture % the posture displayes
        posture_exist = false % whether the posture is generated
        section_controllers % Sliders or EditFields to control rotation of sections
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
%             app.LeftCalfSlider
            
            app.posture = robot('posture');
            app.posture_exist = true;
            app.posture.draw(app.UIAxes);
            
            app.HeadSlider.Enable = true;
            app.HeadEditField.Enable = true;
        end

        % Value changing function: HeadSlider
        function HeadSliderValueChanging(app, event)
            value = event.Value;
            app.HeadEditField.Value = value;
            
            delta_alpha = value - app.posture.head.rotation_angle;
            app.posture.head.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);
        end

        % Value changed function: HeadEditField
        function HeadEditFieldValueChanged(app, event)
            value = app.HeadEditField.Value;
            
            if value < app.HeadSlider.Limits(1)
                app.HeadSlider.Value = app.HeadSlider.Limits(1);
            elseif value > app.HeadSlider.Limits(2)
                app.HeadSlider.Value = app.HeadSlider.Limits(2);
            else
                app.HeadSlider.Value = value;
            end
            
            delta_alpha = value - app.posture.head.rotation_angle;
            app.posture.head.rotation(delta_alpha);
            app.posture.update_position();
            app.posture.draw(app.UIAxes);            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {660, 660, 660};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {660, 660};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {123, '1x', 326};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [0 16];
            app.RightPanel = uipanel(app.GridLayout);
            app.HeadSliderLabel = uilabel(app.RightPanel);
            app.HeadSliderLabel.Text = 'Head';
            app.HeadSlider = uislider(app.RightPanel);
            app.HeadSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.HeadSlider.MajorTicks = [-3.14159265358979 3.14159265358979];
            app.HeadSlider.MajorTickLabels = {'-pi', 'pi'};
            app.HeadSlider.ValueChangingFcn = createCallbackFcn(app, @HeadSliderValueChanging, true);
            app.HeadSlider.Enable = 'off';
            app.HeadEditField = uieditfield(app.RightPanel, 'numeric');
            app.HeadEditField.ValueChangedFcn = createCallbackFcn(app, @HeadEditFieldValueChanged, true);
            app.HeadEditField.Enable = 'off';
            app.EditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_2.Enable = 'off';
            app.ChestSliderLabel = uilabel(app.RightPanel);
            app.ChestSliderLabel.Text = 'Chest';
            app.ChestSlider = uislider(app.RightPanel);
            app.ChestSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.ChestSlider.Enable = 'off';
            app.EditField_3 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_3.Enable = 'off';
            app.WaistSliderLabel = uilabel(app.RightPanel);
            app.WaistSliderLabel.Text = 'Waist';
            app.WaistSlider = uislider(app.RightPanel);
            app.WaistSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.WaistSlider.Enable = 'off';
            app.EditField_4 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_4.Enable = 'off';
            app.LeftArmSliderLabel = uilabel(app.RightPanel);
            app.LeftArmSliderLabel.Text = 'Left Arm';
            app.LeftArmSlider = uislider(app.RightPanel);
            app.LeftArmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftArmSlider.Enable = 'off';
            app.EditField_5 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_5.Enable = 'off';
            app.LeftForearmSliderLabel = uilabel(app.RightPanel);
            app.LeftForearmSliderLabel.Text = 'Left Forearm';
            app.LeftForearmSlider = uislider(app.RightPanel);
            app.LeftForearmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftForearmSlider.Enable = 'off';
            app.EditField_6 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_6.Enable = 'off';
            app.RightArmSliderLabel = uilabel(app.RightPanel);
            app.RightArmSliderLabel.Text = 'Right Arm';
            app.RightArmSlider = uislider(app.RightPanel);
            app.RightArmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightArmSlider.Enable = 'off';
            app.EditField_7 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_7.Enable = 'off';
            app.RightForearmSliderLabel = uilabel(app.RightPanel);
            app.RightForearmSliderLabel.Text = 'Right Forearm';
            app.RightForearmSlider = uislider(app.RightPanel);
            app.RightForearmSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightForearmSlider.Enable = 'off';
            app.EditField_8 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_8.Enable = 'off';
            app.LeftThighSliderLabel = uilabel(app.RightPanel);
            app.LeftThighSliderLabel.Text = 'Left Thigh';
            app.LeftThighSlider = uislider(app.RightPanel);
            app.LeftThighSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftThighSlider.Enable = 'off';
            app.EditField_9 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_9.Enable = 'off';
            app.LeftCalfSliderLabel = uilabel(app.RightPanel);
            app.LeftCalfSliderLabel.Text = 'Left Calf';
            app.LeftCalfSlider = uislider(app.RightPanel);
            app.LeftCalfSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.LeftCalfSlider.Enable = 'off';
            app.EditField_10 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_10.Enable = 'off';
            app.RightThighSliderLabel = uilabel(app.RightPanel);
            app.RightThighSliderLabel.Text = 'Right Thigh';
            app.RightThighSlider = uislider(app.RightPanel);
            app.RightThighSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightThighSlider.Enable = 'off';
            app.EditField_11 = uieditfield(app.RightPanel, 'numeric');
            app.EditField_11.Enable = 'off';
            app.RightCalfSliderLabel = uilabel(app.RightPanel);
            app.RightCalfSliderLabel.Text = 'Right Calf';
            app.RightCalfSlider = uislider(app.RightPanel);
            app.RightCalfSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.RightCalfSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = posture_editer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end